classdef app_ML_predict_CNT_confined_density < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        InputPanel            matlab.ui.container.Panel
        TemperatureKLabel     matlab.ui.control.Label
        Temperature           matlab.ui.control.NumericEditField
        PressureMPaLabel      matlab.ui.control.Label
        Pressure              matlab.ui.control.NumericEditField
        CNTdiameterLabel      matlab.ui.control.Label
        Diameter              matlab.ui.control.NumericEditField
        BulkdensityKgm3Label  matlab.ui.control.Label
        Bulk_density          matlab.ui.control.NumericEditField
        OutputPanel           matlab.ui.container.Panel
        ConfineddensityKgm3EditFieldLabel  matlab.ui.control.Label
        Confined_density      matlab.ui.control.NumericEditField
        Calculate             matlab.ui.control.Button
        Reset                 matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Calculate
        function CalculateButtonPushed(app, event)
            T = app.Temperature.Value;
            P = app.Pressure.Value;
            D = app.Diameter.Value;
            Den_b = app.Bulk_density.Value;
            load('net.mat')
            in_all = [D T P Den_b]';
            In_all = mapminmax('apply',in_all,ps_input);
            Sim_all = sim(net,In_all);
            sim_all = mapminmax('reverse',Sim_all,ps_output);
            Den_c = sim_all;
            app.Confined_density.Value = Den_c;
            
            X = categorical({'Bulk density','Confined density'});
            X = reordercats(X,{'Bulk density','Confined density'});
            Y = [Den_b Den_c];
            bar(app.UIAxes,X,Y,'m')
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            app.Temperature.Value = 0;
            app.Pressure.Value = 0;
            app.Diameter.Value = 0;
            app.Bulk_density.Value = 0;
            app.Confined_density.Value = 0;
            
            X = categorical({'Bulk density','Confined density'});
            X = reordercats(X,{'Bulk density','Confined density'});
            Y = [0 0];
            bar(app.UIAxes,X,Y)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.TemperatureKLabel = uilabel(app.InputPanel);
            app.TemperatureKLabel.Text = 'Temperature (K):';
            app.Temperature = uieditfield(app.InputPanel, 'numeric');
            app.Temperature.Tag = 'T';
            app.PressureMPaLabel = uilabel(app.InputPanel);
            app.PressureMPaLabel.Text = 'Pressure (MPa):';
            app.Pressure = uieditfield(app.InputPanel, 'numeric');
            app.CNTdiameterLabel = uilabel(app.InputPanel);
            app.CNTdiameterLabel.Text = 'CNT diameter (Å):';
            app.Diameter = uieditfield(app.InputPanel, 'numeric');
            app.BulkdensityKgm3Label = uilabel(app.InputPanel);
            app.BulkdensityKgm3Label.Text = 'Bulk density (Kg/m^3):';
            app.Bulk_density = uieditfield(app.InputPanel, 'numeric');
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.ConfineddensityKgm3EditFieldLabel = uilabel(app.OutputPanel);
            app.ConfineddensityKgm3EditFieldLabel.Text = 'Confined density (Kg/m^3):';
            app.Confined_density = uieditfield(app.OutputPanel, 'numeric');
            app.Calculate = uibutton(app.OutputPanel, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.Calculate.Text = 'Calculate';
            app.Reset = uibutton(app.OutputPanel, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Reset';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Predicting the confined density of water in carbon nanotubes using neural networks';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Bulk and confined density of water')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'Density (Kg/m^3)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_ML_predict_CNT_confined_density

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end